classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StartButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        coords = [0,0];
        maxSize = 50;
        coolArray;
    end
    
    methods (Access = private)
        function start(app)

            app.coolArray = zeros(80, app.maxSize);

            % two temp filler arrays
            assignin('base', 'arrayIn', 1:80);
            assignin('base', 'arrayOut', (1:80)*2);

            % create uifigure and pause button
            fig = uifigure(800,800);
            pb = uibutton(fig, 'state', 'Position', [400, 300, 100, 22], 'Text', "Pause Button");
            pb.Value = false;

            sb = uibutton(fig, 'state', 'Position', [400, 200, 100, 22], 'Text', "Restart Button");
            sb.Value = false;

            % get coordinates
%             ax3 = uiaxes(fig, 'Position', [100, 10, 100, 100]);
%             imshow('image.jpg', 'Parent', ax3);
            imshow('image.jpg');
            cords = getCoords(app);
            assignin('base', 'cords', cords);
            
            % while not stopped, check if paused
            while  (~sb.Value)

                % update if not paused
                if (~pb.Value)

%                     if(newArray)
                        showArray(app, fig);
%                     if(new arrayOut)
                        updateMap(app, fig);
                    pause(0.01);
                end

                drawnow();
            end
            restart(app, fig);

        end


        function restart(app, fig)
            close all;
            app.coolArray = zeros(80, app.maxSize);
            
            pb = uibutton(fig, 'state', 'Position', [400, 300, 100, 22], 'Text', "Pause Button");
            pb.Value = false;
            sb = uibutton(fig, 'state', 'Position', [400, 200, 100, 22], 'Text', "Restart Button");
            sb.Value = false;


            imshow('image.jpg');
            cords = getCoords(app);
            assignin('base', 'cords', cords);
            
            % while not stopped, check if paused
            while  (~sb.Value)

                % update if not paused
                if (~pb.Value)

%                     if(new arrayIn)
                        showArray(app, fig);
%                     if(new arrayOut)
                        updateMap(app, fig);
                    pause(0.01);
                end

                drawnow();
            end
            restart(app, fig);
        end
        

        function x = getCoords(app)
            app.coords = ginput(1);
            x = app.coords;
%             assignin('base', 'coords', app.coords);
        end
        
        % gets inputted array and displays
        function showArray(app, f)
            ax = uiaxes(f, 'Position', [10 10 100 100]);
            plot(ax, evalin('base', 'arrayIn'));
        end
        
        % updates grayscale map with new array
        function updateMap(app, f)
            arrayOut = evalin('base', 'arrayOut');
            arrayOut = arrayOut';
            
            temp = max(arrayOut);
            arrayOut = arrayOut/temp;

            app.coolArray = [arrayOut, app.coolArray];
            if (size(app.coolArray, 2) > app.maxSize)
                app.coolArray(:,app.maxSize + 1) = [];
            end
            
            ax2 = uiaxes(f, 'Position', [10, 200, 200, 200]);
            imshow(app.coolArray, 'Parent', ax2);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function PauseButtonPushed(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.start();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end